classdef Brewery_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BreweryApplicationUIFigure     matlab.ui.Figure
        MaltEditorPanel                matlab.ui.container.Panel
        AddMaltRowButton               matlab.ui.control.Button
        MaltTable                      matlab.ui.control.Table
        RemoveMaltRowButton            matlab.ui.control.Button
        PotentialEditFieldLabel        matlab.ui.control.Label
        PotentialEditField             matlab.ui.control.NumericEditField
        NameLabel                      matlab.ui.control.Label
        MaltNameEditField              matlab.ui.control.EditField
        EfficiencyLabel                matlab.ui.control.Label
        EfficiencyEditField            matlab.ui.control.NumericEditField
        GrainweightlbEditFieldLabel    matlab.ui.control.Label
        GrainweightlbEditField         matlab.ui.control.NumericEditField
        HopEditorPanel                 matlab.ui.container.Panel
        NameLabel_2                    matlab.ui.control.Label
        HopNameEditField               matlab.ui.control.EditField
        AAEditFieldLabel               matlab.ui.control.Label
        AAEditField                    matlab.ui.control.NumericEditField
        WeightozEditFieldLabel         matlab.ui.control.Label
        WeightozEditField              matlab.ui.control.NumericEditField
        AddHopRowButton                matlab.ui.control.Button
        RemoveHopRowButton             matlab.ui.control.Button
        HopTable                       matlab.ui.control.Table
        TimeminEditFieldLabel          matlab.ui.control.Label
        TimeminEditField               matlab.ui.control.NumericEditField
        BeerinformationPanel           matlab.ui.container.Panel
        ABVLabel                       matlab.ui.control.Label
        IBULabel                       matlab.ui.control.Label
        OGLabel                        matlab.ui.control.Label
        FGLabel                        matlab.ui.control.Label
        BreweryOptionsPanel            matlab.ui.container.Panel
        BatchsizegallonsLabel          matlab.ui.control.Label
        InitialVolume                  matlab.ui.control.NumericEditField
        YeastefficiencyEditFieldLabel  matlab.ui.control.Label
        YeastefficiencyEditField       matlab.ui.control.NumericEditField
        ScaledBatchsizegallonsLabel    matlab.ui.control.Label
        FinalVolume                    matlab.ui.control.NumericEditField
        PreboilsizegallonsLabel        matlab.ui.control.Label
        PreboilsizegallonsEditField    matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        lastMaltSelected = 0; % Last malt row selected 
        OG = 0; % Original gravity
        lastHopSelected = 0;% Last hop row selected
        lastMaltTable = []; % Description
    end
    
    methods (Access = private)
        
        % General table updating function.
        function [updatedMatrix] = UpdateTable(~, currentMatrix, newRow, rowNumber)
            % Name, Potential, Weight, Efficiency, rowNumber
                  
            % Add the new row to the tables matrix. 
            % Returns 0 if no row is changed, so therefore add. 
            if rowNumber == 0
                % Add new row
                updatedMatrix = [currentMatrix;newRow];
       
            else 
                % Change the specified row
                updatedMatrix = currentMatrix;
                
                % Update the old row.
                updatedMatrix(rowNumber,:) = newRow;
            end
        end
        
        % Gets the sum of all rows in a column
        function sumColumn = sumOfColumn(~, matrix, column)
            
            % Get the number of active rows.
            [rows, ~] = size(matrix);
            
            sumColumn = 0; 
            
            % Add all the values.
            for i = 1 : rows
                sumColumn = sumColumn + matrix{i, column};
            end
        end
        
        %{
            Original gravity formulas. 
        %}
       
        % Fetches the original gravity.
        function OG = GetOG(app)
            totalPPG = 0;
            matrix = app.MaltTable.Data;
            
            [rowSize, ~] = size(matrix);
            
            for i = 1: rowSize
                totalPPG = totalPPG + matrix{i, 5};
            end
            
            % Calculate variables. 
            Weighted = GetWeightedEfficiency(app);
            disp(Weighted);
            OG = ((totalPPG*Weighted + 1000)/1000);

        end

        function WeightedEfficiency = GetWeightedEfficiency(app)
        
            WeightedEfficiency = 0;
            
            % Grab data
            matrix = app.MaltTable.Data;
            [rowSize, ~] = size(matrix);
            
            
            totalWeight = sumOfColumn(app, matrix, 4);
            
            for i = 1 : rowSize
                WeightedEfficiency = WeightedEfficiency + ((matrix{i,3}/100)*matrix{i,4})/totalWeight;
            end

        end
        
        %{
            Matrix row format functions. 
        %}
        
        function Row = GetMaltRow(app, Name, Potential, Weight, Efficiency)
                % Calulate Points
                Points = (Potential*1000) - 1000;
                
                % Calculate PPG
                PPG = ((Points*(Weight))/app.FinalVolume.Value);
                
                % Create a new row to add to matrix. 
                Row = {Name, Potential, Efficiency, Weight, PPG};
        end
        
        function Row = GetHopRow(app, Name, AA, Weight, Time)
            IBU = GetIBU(app,AA,Weight,Time);
            
            Row = {Name, AA, Weight, Time, IBU};
        end
        
        %{
            IBU calculation functions. 
        %}
        
        function Utilization = GetUtilization(app, Time)
            % From math formula from the shared document
            boilGravity = (((app.OG * 1000) - 1000)*app.InitialVolume.Value/app.PreboilsizegallonsEditField.Value + 1000)/1000; 
            g = 1.65 * 0.000125^(boilGravity - 1);
            t = (1 - exp(-0.04 * Time)) / 4.15;
            Utilization = g*t;     
            fprintf('Utilization: %g\n', Utilization);
        end
        
        function IBU = GetIBU(app, AA, Weight, Time)
            % Alpha Acid Units
            AAU = Weight*AA;
            Utilization = GetUtilization(app, Time);
            IBU = (AAU*Utilization*75)/app.FinalVolume.Value;
        end
        
        %{
            Label updating functions.
        %}
        
        
        % Update general malt information
        function UpdateMaltInfo(app)
            app.OG = GetOG(app);
            % Calculate force of gravity.
            FG = ((app.OG*1000 - 1000)*(1.0-(app.YeastefficiencyEditField.Value/100)) + 1000)/1000;

            % Update gravity labels
            app.OGLabel.Text = sprintf('OG: %g\n', app.OG);
            app.FGLabel.Text = sprintf('FG: %g\n', FG);

            fprintf("FG: %g\n", FG);
            ABV = 100*(1.05*(app.OG - FG))/(FG*0.79);
            
            % Update ABV label.
            app.ABVLabel.Text = sprintf('ABV: %g%', ABV);
            fprintf('Original gravity: %g\n', app.OG);
            
            % Update malt table variable
            app.lastMaltTable = app.MaltTable.Data;
            
            % Update all the hop rows with updated gravities.
            if ~isempty(app.HopTable.Data)
                UpdateHopRows(app);
                UpdateHopInfo(app);
            end
        end
        
        % Updates hops values
        function UpdateHopInfo(app)
            totalIBU = 0;
            
            % Fetch size of matrix
            [columnSize, ~] = size(app.HopTable.Data);
            
            for i = 1: columnSize
                totalIBU = totalIBU + app.HopTable.Data{i, 5};
            end
            fprintf(sprintf('IBU: %g', totalIBU));
            app.IBULabel.Text = sprintf('IBU: %g%', totalIBU);
        end
        
        %{
            Row updating functions.
        %}
        
        % Updates all the malt rows. 
        function UpdateMaltRows(app)
        
            % Get the number of active rows.
            [rowSize, ~] = size(app.MaltTable.Data);

            for s = 1 : rowSize
                % Grab all existing data from a row
                Name = app.MaltTable.Data{s, 1};
                Potential = app.MaltTable.Data{s, 2};
                Efficiency = app.MaltTable.Data{s, 3};
                Weight = app.MaltTable.Data{s, 4};
            
                        
                % Create new row to add to Table
                newRow = GetMaltRow(app, Name, Potential, Weight, Efficiency);
                
                % Update the one row.
                app.MaltTable.Data = UpdateTable(app, app.MaltTable.Data, newRow, s);
            end
        end
        
        % Updates all the hop rows. 
        function UpdateHopRows(app)
        % Get the number of active rows.
            [rowSize, ~] = size(app.HopTable.Data);

            for s = 1 : rowSize
                % Grab all existing data from a row
                Name = app.HopTable.Data{s,1};
                AlphaAcid = app.HopTable.Data{s,2};
                Weight = app.HopTable.Data{s,3};
                Time = app.HopTable.Data{s,4};
            
                % Create new row to add to Table
                newRow = GetHopRow(app, Name, AlphaAcid, Weight, Time);
                
                % Update the one row.
                app.HopTable.Data = UpdateTable(app, app.HopTable.Data, newRow, s);
            end
        end
        
        
        %{
            Alerting user and data confirmation functions.
        %}
        
        % Opens an ok dialogue to warn the user of a problem.
        function AlertUser(app, title, message, icon)
            appfigure = app.BreweryApplicationUIFigure;
            
            % Send a warning to the console.
            warning(message);
            
            % Display the warning to the user.
            uialert(appfigure, message, title, 'Icon', icon);
        end
        
        % Default warning function
        function ErrorUser(app, message)
            % Pass extra arguments into AlertUser function
            AlertUser(app, 'Error', message, 'error')
        end
        
        % Return whether or not it was confirmed
        function confirmed = ConfirmWarning(app, title, message)
            appfigure = app.BreweryApplicationUIFigure;
            
            warning(message);
            
            % Get the selection 
            selection = uiconfirm(appfigure, message, title, "Icon","warning");
            
            % Verify they would like to continue. 
            confirmed = strcmp(selection,'OK');
        end
        
        % Verify that all malt input is fine, else give errors or warnings.
        function confirmed = ConfirmMaltInput(app, Name, Potential, Weight, Efficiency, recheck)
            % Starts off as false, can only become true by reaching the
            % last option. 
            confirmed = false;
            if strlength(Name) <= 0
                % Check if the name field is empty.
                ErrorUser(app, sprintf('The malt name text field cannot be empty.'));
            elseif Potential <= 1.0
                % Make sure the potential is the correct value. 
                ErrorUser(app, sprintf('The potential must be greater than water (1.0)'));
            elseif Potential > 1.1 && ~recheck
                confirmedWarning = ConfirmWarning(app, 'High Potential',...
                    sprintf('The potential of your malt seems high. \n Would you like to continue?'));
                % Verify rest of options if they confirm. 
                if confirmedWarning 
                    % Call recursive function to verify that all other
                    % options do not cause an error.
                    confirmed = ConfirmMaltInput(app, Name, Potential, Weight, Efficiency, true);
                end
            elseif Weight <= 0
                ErrorUser(app, sprintf('The weight of the malt must be greater than 0 pounds.'));
            elseif  Efficiency <= 0 || Efficiency > 100
                ErrorUser(app, ...
                    sprintf("Efficiency is a percent, must be greater than 0 \nand less than 100"));
            else
                % If everything else is true, then it has been confirmed.
                confirmed = true; 
            end
        end
        
        function confirmed = ConfirmHopInput(app, Name, AlphaAcid, Weight, Time)
            % Initially is not confirmed.
            confirmed = false;
            if isempty(app.MaltTable.Data)
                ErrorUser(app, "Fill out malt table before filling out hop table.");
            elseif strlength(Name) <= 0
                ErrorUser(app, "The hop name text field cannot be empty.");
            elseif AlphaAcid <= 0 || AlphaAcid > 100
                ErrorUser(app,...
                    sprintf("Alpha acid is a percent, must be greater than 0 \nand less than 100"));
            elseif Weight <= 0
                ErrorUser(app, "The weight of the malt must be greater than 0 pounds.");
            elseif  Time <= 0
                ErrorUser(app, "The time for the hops must be greater than 0 minutes.")
                return;
            else
                % If all else is false, then it is confirmed.
                confirmed = true; 
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Cell edit callback: MaltTable
        function MaltTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
          
            % Indices(1) is row Indices (2) is column 
            changedRow = indices(1);
            
            % Grab all the updated data.
            Name = app.MaltTable.Data{changedRow, 1};
            Potential = app.MaltTable.Data{changedRow,2};
            Efficiency = app.MaltTable.Data{changedRow, 3};
            Weight = app.MaltTable.Data{changedRow, 4};
            
            
            if ~ConfirmMaltInput(app, Name, Potential, Weight, Efficiency, false)
                % Reject their changes if the changes are not confirmed. 
                app.MaltTable.Data = app.lastMaltTable;
                return;
            end
            
            % Create new row to add to Table
            newRow = GetMaltRow(app, Name, Potential, Weight, Efficiency);

            % Update the edited table row
            app.MaltTable.Data = UpdateTable(app, app.MaltTable.Data, newRow, changedRow);
            UpdateMaltInfo(app);
        end

        % Button pushed function: AddMaltRowButton
        function AddMaltRowButtonPushed(app, event)
            % Declare all variable values. 
            Name = app.MaltNameEditField.Value;
            Potential = app.PotentialEditField.Value;
            Weight = app.GrainweightlbEditField.Value*app.FinalVolume.Value/app.InitialVolume.Value;
            Efficiency = app.EfficiencyEditField.Value;
            
            if ~ConfirmMaltInput(app, Name, Potential, Weight, Efficiency, false)
                % Reject their input if not verified. 
                return;
            end
            
            % Create a new row for malt. 
            newRow = GetMaltRow(app, Name, Potential, Weight, Efficiency);
            
            % Update all the malt information.
            app.MaltTable.Data = UpdateTable(app, app.MaltTable.Data, newRow, 0);
            UpdateMaltInfo(app);
            
        end

        % Button pushed function: RemoveMaltRowButton
        function RemoveMaltRowButtonPushed(app, event)
            if app.lastMaltSelected == 0
                ErrorUser(app, 'Cannot remove malt row, as no row was selected.');
                return;
            end

            % Set row to blank. 
            app.MaltTable.Data(app.lastMaltSelected, :) = [];
            
            UpdateMaltInfo(app);
        end

        % Cell selection callback: MaltTable
        function MaltTableCellSelection(app, event)
            % Get the row of the selected cell
            disp(event.Indices(1));
            app.lastMaltSelected = event.Indices(1);
        end

        % Button pushed function: AddHopRowButton
        function AddHopRowButtonPushed(app, event)
            % Declare all variable values. 
            Name = app.HopNameEditField.Value;
            AlphaAcid = app.AAEditField.Value;

            Weight = app.WeightozEditField.Value*app.FinalVolume.Value/app.InitialVolume.Value;
            Time = app.TimeminEditField.Value;
            
            % Verify that all their hop input is valid. 
            if ~ConfirmHopInput(app, Name, AlphaAcid, Weight, Time)
                % if false, reject their input. 
                return;
            end
            
            % Update all the hop table data. 
            newRow = GetHopRow(app, Name, AlphaAcid, Weight, Time);
            app.HopTable.Data = UpdateTable(app, app.HopTable.Data, newRow, 0);
            UpdateHopInfo(app);
        end

        % Button pushed function: RemoveHopRowButton
        function RemoveHopRowButtonPushed(app, event)
            if app.lastHopSelected == 0
                ErrorUser(app, 'Cannot remove hop row, as no row was selected.');
                return;
            end
            disp(app.lastMaltSelected);
            % Set row to blank. 
            app.HopTable.Data(app.lastHopSelected, :) = [];
            
            UpdateHopInfo(app);
        end

        % Cell selection callback: HopTable
        function HopTableCellSelection(app, event)
            indices = event.Indices;
            % Update the last selected malt.
            app.lastHopSelected = indices(1);
        end

        % Cell edit callback: HopTable
        function HopTableCellEdit(app, event)
            indices = event.Indices;
            
            % Indices(1) is row Indices (2) is column 
            changedRow = indices(1);
            
            % Grab all the updated data.
            Name = app.HopTable.Data{changedRow,1};
            AlphaAcid = app.HopTable.Data{changedRow,2};
            Weight = app.HopTable.Data{changedRow,3};
            Time = app.HopTable.Data{changedRow,4};
            
            % Verify that all their hop input is valid. 
            if ~ConfirmHopInput(app, Name, AlphaAcid, Weight, Time)
                % if false, reject their input. 
                return;
            end
            
            % Create new row to add to Table
            newRow = GetHopRow(app, Name, AlphaAcid, Weight, Time);

            % Update the edited table row
            app.HopTable.Data = UpdateTable(app, app.HopTable.Data, newRow, changedRow);
            UpdateHopInfo(app);
        end

        % Value changed function: YeastefficiencyEditField
        function YeastefficiencyEditFieldValueChanged(app, event)
            % Simple update all rows.
            UpdateMaltRows(app);
            
            UpdateMaltInfo(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BreweryApplicationUIFigure = uifigure('Visible', 'off');
            app.BreweryApplicationUIFigure.Name = 'Brewery Application';
            app.MaltEditorPanel = uipanel(app.BreweryApplicationUIFigure);
            app.MaltEditorPanel.Title = 'Malt Editor';
            app.AddMaltRowButton = uibutton(app.MaltEditorPanel, 'push');
            app.AddMaltRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddMaltRowButtonPushed, true);
            app.AddMaltRowButton.Text = 'Add Row';
            app.MaltTable = uitable(app.MaltEditorPanel);
            app.MaltTable.ColumnName = {'Name'; 'Potential'; 'Efficiency'; 'Weight (lbs)'; 'PPG'};
            app.MaltTable.RowName = {''};
            app.MaltTable.ColumnSortable = true;
            app.MaltTable.ColumnEditable = [true true true true false];
            app.MaltTable.CellEditCallback = createCallbackFcn(app, @MaltTableCellEdit, true);
            app.MaltTable.CellSelectionCallback = createCallbackFcn(app, @MaltTableCellSelection, true);
            app.RemoveMaltRowButton = uibutton(app.MaltEditorPanel, 'push');
            app.RemoveMaltRowButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveMaltRowButtonPushed, true);
            app.RemoveMaltRowButton.Text = 'Remove Row';
            app.PotentialEditFieldLabel = uilabel(app.MaltEditorPanel);
            app.PotentialEditFieldLabel.Text = 'Potential';
            app.PotentialEditField = uieditfield(app.MaltEditorPanel, 'numeric');
            app.PotentialEditField.Value = 1.037;
            app.NameLabel = uilabel(app.MaltEditorPanel);
            app.NameLabel.Text = 'Name';
            app.MaltNameEditField = uieditfield(app.MaltEditorPanel, 'text');
            app.MaltNameEditField.Value = 'Pale';
            app.EfficiencyLabel = uilabel(app.MaltEditorPanel);
            app.EfficiencyLabel.Text = 'Efficiency (%)';
            app.EfficiencyEditField = uieditfield(app.MaltEditorPanel, 'numeric');
            app.EfficiencyEditField.Value = 80;
            app.GrainweightlbEditFieldLabel = uilabel(app.MaltEditorPanel);
            app.GrainweightlbEditFieldLabel.Text = 'Grain weight (lb)';
            app.GrainweightlbEditField = uieditfield(app.MaltEditorPanel, 'numeric');
            app.GrainweightlbEditField.Value = 12;
            app.HopEditorPanel = uipanel(app.BreweryApplicationUIFigure);
            app.HopEditorPanel.Title = 'Hop Editor';
            app.NameLabel_2 = uilabel(app.HopEditorPanel);
            app.NameLabel_2.Text = 'Name';
            app.HopNameEditField = uieditfield(app.HopEditorPanel, 'text');
            app.HopNameEditField.Value = 'Columbus';
            app.AAEditFieldLabel = uilabel(app.HopEditorPanel);
            app.AAEditFieldLabel.Text = 'AA (%)';
            app.AAEditField = uieditfield(app.HopEditorPanel, 'numeric');
            app.WeightozEditFieldLabel = uilabel(app.HopEditorPanel);
            app.WeightozEditFieldLabel.Text = 'Weight (oz)';
            app.WeightozEditField = uieditfield(app.HopEditorPanel, 'numeric');
            app.AddHopRowButton = uibutton(app.HopEditorPanel, 'push');
            app.AddHopRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddHopRowButtonPushed, true);
            app.AddHopRowButton.Text = 'Add Row';
            app.RemoveHopRowButton = uibutton(app.HopEditorPanel, 'push');
            app.RemoveHopRowButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveHopRowButtonPushed, true);
            app.RemoveHopRowButton.Text = 'Remove Row';
            app.HopTable = uitable(app.HopEditorPanel);
            app.HopTable.ColumnName = {'Name'; 'AA (%)'; 'Weight (oz)'; 'Time (min)'; 'IBU'};
            app.HopTable.RowName = {};
            app.HopTable.ColumnSortable = true;
            app.HopTable.ColumnEditable = [true true true true false];
            app.HopTable.CellEditCallback = createCallbackFcn(app, @HopTableCellEdit, true);
            app.HopTable.CellSelectionCallback = createCallbackFcn(app, @HopTableCellSelection, true);
            app.TimeminEditFieldLabel = uilabel(app.HopEditorPanel);
            app.TimeminEditFieldLabel.Text = 'Time (min)';
            app.TimeminEditField = uieditfield(app.HopEditorPanel, 'numeric');
            app.BeerinformationPanel = uipanel(app.BreweryApplicationUIFigure);
            app.BeerinformationPanel.Title = 'Beer information';
            app.ABVLabel = uilabel(app.BeerinformationPanel);
            app.ABVLabel.Text = 'ABV:';
            app.IBULabel = uilabel(app.BeerinformationPanel);
            app.IBULabel.Text = 'IBU:';
            app.OGLabel = uilabel(app.BeerinformationPanel);
            app.OGLabel.Text = 'OG: ';
            app.FGLabel = uilabel(app.BeerinformationPanel);
            app.FGLabel.Text = 'FG: ';
            app.BreweryOptionsPanel = uipanel(app.BreweryApplicationUIFigure);
            app.BreweryOptionsPanel.Title = 'Brewery Options';
            app.BatchsizegallonsLabel = uilabel(app.BreweryOptionsPanel);
            app.BatchsizegallonsLabel.Text = 'Batch size (gallons)';
            app.InitialVolume = uieditfield(app.BreweryOptionsPanel, 'numeric');
            app.InitialVolume.Value = 5;
            app.YeastefficiencyEditFieldLabel = uilabel(app.BreweryOptionsPanel);
            app.YeastefficiencyEditFieldLabel.Text = 'Yeast efficiency';
            app.YeastefficiencyEditField = uieditfield(app.BreweryOptionsPanel, 'numeric');
            app.YeastefficiencyEditField.ValueChangedFcn = createCallbackFcn(app, @YeastefficiencyEditFieldValueChanged, true);
            app.YeastefficiencyEditField.Value = 80;
            app.ScaledBatchsizegallonsLabel = uilabel(app.BreweryOptionsPanel);
            app.ScaledBatchsizegallonsLabel.Text = 'Scaled Batch size (gallons)';
            app.FinalVolume = uieditfield(app.BreweryOptionsPanel, 'numeric');
            app.FinalVolume.Value = 310;
            app.PreboilsizegallonsLabel = uilabel(app.BreweryOptionsPanel);
            app.PreboilsizegallonsLabel.Text = 'Pre boil size (gallons)';
            app.PreboilsizegallonsEditField = uieditfield(app.BreweryOptionsPanel, 'numeric');
            app.PreboilsizegallonsEditField.Value = 6;
            % Show the figure after all components are created
            app.BreweryApplicationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Brewery_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BreweryApplicationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BreweryApplicationUIFigure)
        end
    end
end